classdef Snake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        EditField              matlab.ui.control.EditField
        EditFieldLabel         matlab.ui.control.Label
        HighestEditField       matlab.ui.control.NumericEditField
        PressStartButton       matlab.ui.control.Button
        HighestEditFieldLabel  matlab.ui.control.Label
        ScoreEditField         matlab.ui.control.NumericEditField
        ScoreEditFieldLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        dead= false; % is snake dead?
        screenheight=60; % axis height
        screenwidth=60; % axis width
        foodposx=0;
        foodposy=0;
        direction=0;  % start facing left
        newdirection=0; %direction after arrow press
        snake=[40:50;repmat(30,[1 11])]; % snake starting position & length
        score=0;
        highscore=0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PressStartButton
        function PressStartButtonPushed(app, event)
            % initialize each time start button is pressed
            app.dead= false;
            app.screenheight=60;
            app.screenwidth=60;
            app.foodposx=0;
            app.foodposy=0;
            app.direction=0;
            app.newdirection=0;
            app.snake=[40:50;repmat(30,[1 11])];
            app.score=0;

            % generate food
            app.foodposx=ceil(rand(1)*(app.screenwidth-10))+3;
            app.foodposy=ceil(rand(1)*(app.screenheight-10))+3;

            % if snake is not dead then play continues
            while ~app.dead
                % check if snake head touches the food
                if app.foodposx==app.snake(1,1) && app.foodposy==app.snake(2,1)
                    app.score=app.score+1;
                    % check which direction to increase the tail after food
                    % is eaten
                    if app.snake(1,end)==app.snake(1,end-1)
                        if  app.snake(2,end)>app.snake(2,end-1)
                            app.snake=[ app.snake [app.snake(1,end);app.snake(2,end)+1] [app.snake(1,end);app.snake(2,end)+2] [app.snake(1,end);app.snake(2,end)+3]];
                        else
                            app.snake=[ app.snake [app.snake(1,end);app.snake(2,end)-1] [app.snake(1,end);app.snake(2,end)-2] [app.snake(1,end);app.snake(2,end)-3]];
                        end
                    else
                        if  app.snake(1,end)>app.snake(1,end-1)
                            app.snake=[app.snake [app.snake(1,end)-1;app.snake(2,end)] [app.snake(1,end)-2;app.snake(2,end)] [app.snake(1,end)-3;app.snake(2,end)]];
                        else
                            app.snake=[app.snake [app.snake(1,end)+1;app.snake(2,end)] [app.snake(1,end)+2;app.snake(2,end)] [app.snake(1,end)+3;app.snake(2,end)]];
                        end
                    end

                    % Generate new food
                    app.foodposx=ceil(rand(1)*(app.screenwidth-10))+3;
                    app.foodposy=ceil(rand(1)*(app.screenheight-10))+3;
                    temp1=find(app.snake(1,:)==app.foodposx);
                    temp2=find(app.snake(2,:)==app.foodposy);

                    % if food is generated inside the snake then generate
                    % food again
                    while ~isempty(intersect(temp1,temp2))
                        app.foodposx=ceil(rand(1)*(app.screenwidth-10))+3;
                        app.foodposy=ceil(rand(1)*(app.screenheight-10))+3;
                        temp1=find(app.snake(1,:)==app.foodposx);
                        temp2=find(app.snake(2,:)==app.foodposy);
                    end
                end

                % Get new pressed arrow direction-
                switch app.newdirection
                    case 0 %Left
                        plot(app.UIAxes,app.snake(1,:),app.snake(2,:),'b-o',app.foodposx,app.foodposy,'r-*');
                        app.snake=[[app.snake(1,1)-1;app.snake(2,1)] app.snake];
                        app.snake(:,end)=[];
                    case 1 %UP
                        plot(app.UIAxes,app.snake(1,:),app.snake(2,:),'b-o',app.foodposx,app.foodposy,'r-*');
                        app.snake=[[app.snake(1,1);app.snake(2,1)+1] app.snake];
                        app.snake(:,end)=[];
                    case 2 %Right
                        plot(app.UIAxes,app.snake(1,:),app.snake(2,:),'b-o',app.foodposx,app.foodposy,'r-*');
                        app.snake=[[app.snake(1,1)+1;app.snake(2,1)] app.snake];
                        app.snake(:,end)=[];
                    case 3 %Down
                        plot(app.UIAxes,app.snake(1,:),app.snake(2,:),'b-o',app.foodposx,app.foodposy,'r-*');
                        app.snake=[[app.snake(1,1);app.snake(2,1)-1] app.snake];
                        app.snake(:,end)=[];
                    otherwise
                end
                app.direction=app.newdirection;


                app.ScoreEditField.Value=app.score; % Show score


                pause(.05) % pause to slow down the game speed


                temp1=find(app.snake(1,2:end)==app.snake(1,1));
                temp2=find(app.snake(2,2:end)==app.snake(2,1));


                % Check collision with boundaries and with itself
                if app.snake(1,1)<=0 || app.snake(2,1)<=0 || app.snake(1,1)>=app.screenwidth || app.snake(2,1)>=app.screenheight || ~isempty(intersect(temp1,temp2))
                    plot(app.UIAxes,app.snake(1,:),app.snake(2,:),'r-o');
                    app.dead=true;
                end


                % Set highscore if current score is bigger than previous highscore
                if app.score>app.highscore
                    app.highscore=app.score;
                    app.HighestEditField.Value=app.highscore;
                end
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

            % Arrow direction definition
            key = event.Key;
            switch key
                case 'uparrow'
                    if app.direction~=3
                        app.newdirection=1;
                    end
                case 'downarrow'
                    if app.direction~=1
                        app.newdirection=3;
                    end
                case 'leftarrow'
                    if app.direction~=2
                        app.newdirection=0;
                    end
                case 'rightarrow'
                    if app.direction~=0
                        app.newdirection=2;
                    end
                otherwise
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 60];
            app.UIAxes.YLim = [0 60];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighestEditFieldLabel = uilabel(app.UIFigure);
            app.HighestEditFieldLabel.Text = 'Highest';
            app.PressStartButton = uibutton(app.UIFigure, 'push');
            app.PressStartButton.ButtonPushedFcn = createCallbackFcn(app, @PressStartButtonPushed, true);
            app.PressStartButton.Text = {' Press '; 'Start'};
            app.HighestEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Use Keyboard Arrows';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'arrow.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end